classdef app_subjective_scoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ML4VisADSubjectiveAssessmentcollectorUIFigure  matlab.ui.Figure
        SelectpathofimagesButton    matlab.ui.control.Button
        PathEditFieldLabel          matlab.ui.control.Label
        PathEditField               matlab.ui.control.EditField
        Image                       matlab.ui.control.Image
        logo                        matlab.ui.control.Image
        SelectthetargetclassButtonGroup  matlab.ui.container.ButtonGroup
        StableNormalButton          matlab.ui.control.RadioButton
        StableMCIButton             matlab.ui.control.RadioButton
        StableDementiaButton        matlab.ui.control.RadioButton
        ProgressiveMCItoADButton    matlab.ui.control.RadioButton
        OthersButton                matlab.ui.control.RadioButton
        SelecttheoutputclassyourjudgementButtonGroup  matlab.ui.container.ButtonGroup
        StableNormalorHighProbableNormalButton  matlab.ui.control.RadioButton
        StableMCIorHighProbableMCIButton  matlab.ui.control.RadioButton
        StableDementiaorHighProbableADButton  matlab.ui.control.RadioButton
        ProgressiveMCItoADButton_2  matlab.ui.control.RadioButton
        OthersButton_2              matlab.ui.control.RadioButton
        IndecisiveButton            matlab.ui.control.RadioButton
        NextImageButton             matlab.ui.control.Button
        Stats                       matlab.ui.control.Label
        NumImages_label             matlab.ui.control.Label
        ImageCounter_label          matlab.ui.control.Label
        RID_label                   matlab.ui.control.Label
        StartAssessment             matlab.ui.control.Button
        filename_label              matlab.ui.control.Label
        ML4VisADLabel               matlab.ui.control.Label
        SubjectiveScoreCollectorforLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileNames % Description
        RIDs
        classes_Target
        classes_decided
        FileCounter
        ImageCounter
        NumberOfFiles
        NumberOfDesiredImages
        DIR
        Should_Save
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectpathofimagesButton
        function SelectpathofimagesButtonPushed(app, event)
            selpath = uigetdir("./");
            app.PathEditField.Value=selpath;
            DIR_here=dir(fullfile(app.PathEditField.Value,'*.png'));
            DIR_history=dir(fullfile(app.PathEditField.Value,'*History.png'));
            
            app.NumberOfDesiredImages=length(DIR_here)-length(DIR_history);
            app.NumImages_label.Text=['Number of images: ' num2str(app.NumberOfDesiredImages)];
            
            app.NumberOfFiles=length(DIR_here);
            app.DIR=DIR_here;
            app.StartAssessment.Enable='on';
            
            
        end

        % Button pushed function: StartAssessment
        function StartAssessmentButtonPushed(app, event)
            app.ImageCounter=0;
            app.FileNames={};
            app.RIDs={};
            app.classes_Target={};
            app.classes_decided={};
            
            app.FileCounter=1;
            filename=app.DIR(app.FileCounter).name;
            app.Image.ImageSource=[app.PathEditField.Value '/' filename];
            
            if contains(filename, 'istory')
                
                app.SelectthetargetclassButtonGroup.Visible='off';
                app.SelecttheoutputclassyourjudgementButtonGroup.Visible='off';
                app.Should_Save='false';
            else
                app.ImageCounter=app.ImageCounter+1;
                app.FileNames{app.ImageCounter,1}=filename;
                
                app.ImageCounter_label.Text=['Image: ' num2str(app.ImageCounter)];
                
                k=strfind(filename, 'RID_');
                k2=strfind(filename, '.png');
                
                Rid=filename(k+4:k2-1);
                app.RID_label.Text=['RID: ' Rid];
                app.RIDs{app.ImageCounter,1}=Rid;
                app.Should_Save='true';
                
            end
            
            app.StartAssessment.Enable='off';               
            app.NextImageButton.Enable='on';
            
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            
            
            if strcmp(app.Should_Save,'true')
                
                switch app.SelectthetargetclassButtonGroup.SelectedObject.Text
                    case 'Stable Normal' %where "Button1" is the name of the first radio button
                        val = 'N';
                    case 'Stable MCI'
                        val = 'M';                        
                    case 'Stable Dementia'
                        val = 'D';
                    case 'Progressive MCI to AD'
                        val = 'P';
                    otherwise
                        val = 'X';
                end                
                app.classes_Target{app.ImageCounter,1}=val;
                
                
                switch app.SelecttheoutputclassyourjudgementButtonGroup.SelectedObject.Text
                    case 'Stable Normal or High Probable Normal' %where "Button1" is the name of the first radio button
                        val = 'N';
                    case 'Stable MCI or High Probable MCI'
                        val = 'M';
                    case 'Stable Dementia or High Probable AD'
                        val = 'D';
                    case 'Progressive MCI to AD'
                        val = 'P';
                    case 'Indecisive'
                        val = 'I';
                    otherwise
                        val = 'X';
                end              
                app.classes_decided{app.ImageCounter,1}=val;
                          
                
            end
        
            app.FileCounter=app.FileCounter+1;
            
            if app.FileCounter <= app.NumberOfFiles
                filename=app.DIR(app.FileCounter).name;
                app.Image.ImageSource=[app.PathEditField.Value '/' filename];
                
                if contains(filename, 'istory')
           
                    app.SelectthetargetclassButtonGroup.Visible='off';
                    app.SelecttheoutputclassyourjudgementButtonGroup.Visible='off';
                    app.Should_Save='false';
                    
                else
                    
                    app.ImageCounter=app.ImageCounter+1;
                    app.FileNames{app.ImageCounter,1}=filename;
                    
                    app.ImageCounter_label.Text=['Image: ' num2str(app.ImageCounter)];
                    
                    k=strfind(filename, 'RID_');
                    k2=strfind(filename, '.png');
                    
                    Rid=filename(k+4:k2-1);
                    app.RID_label.Text=['RID: ' Rid];
                    app.RIDs{app.ImageCounter,1} = Rid;
                    
                    app.SelectthetargetclassButtonGroup.Visible='on';                    
                    app.SelecttheoutputclassyourjudgementButtonGroup.Visible='on';
                    app.Should_Save='true';
                    
                end
                
            else
                
                % save and message for the end
                
                saved_RIDs=app.RIDs;
                saved_Filenames=app.FileNames;
                saved_classes_decided=app.classes_decided;
                saved_classes_target=app.classes_Target;
                
                [file,path] = uiputfile;
                k=strfind(file,'.');
                file=file(1:k-1);
                savefilename = fullfile(path,file);                                
                save([savefilename '.mat'],'saved_RIDs','saved_Filenames','saved_classes_decided','saved_classes_target');                
                f = msgbox('Scores are saved!');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ML4VisADSubjectiveAssessmentcollectorUIFigure = uifigure('Visible', 'off');
            app.ML4VisADSubjectiveAssessmentcollectorUIFigure.Name = 'ML4VisAD-Subjective Assessment collector';
            app.SelectpathofimagesButton = uibutton(app.ML4VisADSubjectiveAssessmentcollectorUIFigure, 'push');
            app.SelectpathofimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathofimagesButtonPushed, true);
            app.SelectpathofimagesButton.Text = 'Select path of images';
            app.PathEditFieldLabel = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.ML4VisADSubjectiveAssessmentcollectorUIFigure, 'text');
            app.Image = uiimage(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.logo = uiimage(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.logo.ImageSource = 'Logo_Fiu.png';
            app.SelectthetargetclassButtonGroup = uibuttongroup(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.SelectthetargetclassButtonGroup.Title = 'Select the target class';
            app.StableNormalButton = uiradiobutton(app.SelectthetargetclassButtonGroup);
            app.StableNormalButton.Text = 'Stable Normal';
            app.StableNormalButton.Value = true;
            app.StableMCIButton = uiradiobutton(app.SelectthetargetclassButtonGroup);
            app.StableMCIButton.Text = 'Stable MCI';
            app.StableDementiaButton = uiradiobutton(app.SelectthetargetclassButtonGroup);
            app.StableDementiaButton.Text = 'Stable Dementia';
            app.ProgressiveMCItoADButton = uiradiobutton(app.SelectthetargetclassButtonGroup);
            app.ProgressiveMCItoADButton.Text = 'Progressive MCI to AD';
            app.OthersButton = uiradiobutton(app.SelectthetargetclassButtonGroup);
            app.OthersButton.Text = 'Others';
            app.SelecttheoutputclassyourjudgementButtonGroup = uibuttongroup(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.SelecttheoutputclassyourjudgementButtonGroup.Title = 'Select the output class (your judgement)';
            app.StableNormalorHighProbableNormalButton = uiradiobutton(app.SelecttheoutputclassyourjudgementButtonGroup);
            app.StableNormalorHighProbableNormalButton.Text = 'Stable Normal or High Probable Normal';
            app.StableNormalorHighProbableNormalButton.Value = true;
            app.StableMCIorHighProbableMCIButton = uiradiobutton(app.SelecttheoutputclassyourjudgementButtonGroup);
            app.StableMCIorHighProbableMCIButton.Text = 'Stable MCI or High Probable MCI';
            app.StableDementiaorHighProbableADButton = uiradiobutton(app.SelecttheoutputclassyourjudgementButtonGroup);
            app.StableDementiaorHighProbableADButton.Text = 'Stable Dementia or High Probable AD';
            app.ProgressiveMCItoADButton_2 = uiradiobutton(app.SelecttheoutputclassyourjudgementButtonGroup);
            app.ProgressiveMCItoADButton_2.Text = 'Progressive MCI to AD';
            app.OthersButton_2 = uiradiobutton(app.SelecttheoutputclassyourjudgementButtonGroup);
            app.OthersButton_2.Text = 'Others';
            app.IndecisiveButton = uiradiobutton(app.SelecttheoutputclassyourjudgementButtonGroup);
            app.IndecisiveButton.Text = 'Indecisive';
            app.NextImageButton = uibutton(app.ML4VisADSubjectiveAssessmentcollectorUIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Enable = 'off';
            app.NextImageButton.Text = 'Next Image';
            app.Stats = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.Stats.Text = 'Status';
            app.NumImages_label = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.NumImages_label.Text = 'Number of Images:';
            app.ImageCounter_label = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.ImageCounter_label.Text = 'Image:';
            app.RID_label = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.RID_label.Text = 'RID:';
            app.StartAssessment = uibutton(app.ML4VisADSubjectiveAssessmentcollectorUIFigure, 'push');
            app.StartAssessment.ButtonPushedFcn = createCallbackFcn(app, @StartAssessmentButtonPushed, true);
            app.StartAssessment.Enable = 'off';
            app.StartAssessment.Text = 'Start Assessing';
            app.filename_label = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.filename_label.Text = 'Filename:';
            app.ML4VisADLabel = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.ML4VisADLabel.Text = 'ML4VisAD';
            app.SubjectiveScoreCollectorforLabel = uilabel(app.ML4VisADSubjectiveAssessmentcollectorUIFigure);
            app.SubjectiveScoreCollectorforLabel.Text = 'Subjective Score Collector for';
            % Show the figure after all components are created
            app.ML4VisADSubjectiveAssessmentcollectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_subjective_scoring

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ML4VisADSubjectiveAssessmentcollectorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ML4VisADSubjectiveAssessmentcollectorUIFigure)
        end
    end
end